classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        EditField_4  matlab.ui.control.EditField
        Label_8      matlab.ui.control.Label
        jepgButton   matlab.ui.control.Button
        Spinner      matlab.ui.control.Spinner
        Label_6      matlab.ui.control.Label
        EditField_3  matlab.ui.control.EditField
        Label_7      matlab.ui.control.Label
        Button_2     matlab.ui.control.Button
        Button       matlab.ui.control.Button
        EditField_2  matlab.ui.control.EditField
        Label_5      matlab.ui.control.Label
        EditField    matlab.ui.control.EditField
        Label_4      matlab.ui.control.Label
        DCTButton    matlab.ui.control.Button
        Label_2      matlab.ui.control.Label
        Image        matlab.ui.control.Image
        UIAxes2_2    matlab.ui.control.UIAxes
        UIAxes2      matlab.ui.control.UIAxes
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        msg_len % Description
    end
    
    methods (Access = private)
        
        function data = func(app,img_dct)
            len=app.msg_len;
            img=img_dct;
            data=zeros(1,len);
            T=dctmtx(8);
            img_dct=blkproc(img,[8 8],'P1*x*P2',T,T');
            [row,col]=size(img_dct);
            row=floor(row/8);
            col=floor(col/8);
            a=zeros([row col]);
            [r,c]=randinterval(a,len,2021);
            n=1;
            jump_flag=0;
%             for i=0:(row-1)
%                 for j=0:(col-1)
%                     irow=i*8;
%                     jcol=j*8;
                    while n<=len
                        irow=(r(n)-1)*8;
                        jcol=(c(n)-1)*8;
                        if img_dct(irow+3,jcol+3)<img_dct(irow+6,jcol+6)
                            data(1,n)=0;
                        else
                            data(1,n)=1;
                        end
                        n=n+1;
                    end
                    
%                 end

%             end
        end
        
        function value = func2(app,data)
            fi=fopen("dct_data_out.bin",'w');
            fwrite(fi,data,'ubit1');
            fclose(fi);
            fi=fopen("dct_data_out.bin",'r');
            [s,~]=fread(fi,'int24=>char');
            s1=sprintf('%s',s);
            value=s1;
        end
        
        function img_dct = func3(app,img,data,smaller)
            len=app.msg_len;
            T=dctmtx(8);
            img_dct=blkproc(img,[8 8],'P1*x*P2',T,T');
            [row,col]=size(img_dct);
            row=floor(row/8);
            col=floor(col/8);
            n=1;
            temp=0;
            a=zeros([row,col]);
            [r,c]=randinterval(a,len,2021);
            %smaller=1;%用于调整健壮性
%             for i=0:(row-1)
                %for j=0:(col-1)
%                     irow=r(n)*8;
%                     jcol=c(n)*8;
                    while n<=len
                        irow=(r(n)-1)*8;
                        jcol=(c(n)-1)*8;
                        if data(n)==0 %[3,3]<[6,6]为嵌入0，反之嵌入1。
                            if img_dct(irow+3,jcol+3)<img_dct(irow+6,jcol+6)
                                ;
                            else
                                temp=img_dct(irow+6,jcol+6);
                                img_dct(irow+6,jcol+6)=img_dct(irow+3,jcol+3);
                                img_dct(irow+3,jcol+3)=temp;
                            end
                            img_dct(irow+3,jcol+3)=img_dct(irow+3,jcol+3)-smaller;
                        else
                            if img_dct(irow+3,jcol+3)>img_dct(irow+6,jcol+6)
                                ;
                            else
                                temp=img_dct(irow+6,jcol+6);
                                img_dct(irow+6,jcol+6)=img_dct(irow+3,jcol+3);
                                img_dct(irow+3,jcol+3)=temp;
                            end
                            img_dct(irow+6,jcol+6)=img_dct(irow+6,jcol+6)-smaller;
                        end
                        n=n+1;
                    end
                %end
%             end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function ImageClicked(app, event)
            [filename,pathname]=uigetfile({'*.jpg;*.png;'});
            app.Image.ImageSource=imread(strcat(pathname,filename));
        end

        % Button pushed function: DCTButton
        function DCTButtonPushed(app, event)
            fi=fopen('dct_data.bin','r');
            [data,len]=fread(fi,'ubit1');
            app.msg_len=len;
            fclose(fi);
            T=dctmtx(8);
            smaller=0.1;
            g_img=rgb2gray(app.Image.ImageSource);
            img=im2double(g_img);
            img_dct=func3(app,img,data,smaller);
            img1=blkproc(img_dct,[8 8],'P1*x*P2',T',T);
            imshow(img1,'Parent',app.UIAxes);
            app.UIAxes.Title.String="进行隐藏处理后的图片";
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            fi=fopen('dct_data.bin','w+');
            fwrite(fi,value,'uint24');
            fclose(fi);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            img=getimage(app.UIAxes);
            data=func(app,img);
            app.EditField_2.Value=func2(app,data);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            img_dct=getimage(app.UIAxes);
            img_dct_gn=imnoise(img_dct,"gaussian");
            imshow(img_dct_gn,'Parent',app.UIAxes2);
            data=func(app,img_dct_gn);
            app.EditField_3.Value=func2(app,data);
            app.UIAxes2.Title.String="加入高斯白噪声";
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
            
        end

        % Value changing function: Spinner
        function SpinnerValueChanging(app, event)
            changingValue = event.Value;
            smaller=changingValue;
            T=dctmtx(8);
            fi=fopen("dct_data.bin",'r');
            [data,len]=fread(fi,'ubit1');
            fclose(fi);
            app.msg_len=len;
            g_img=rgb2gray(app.Image.ImageSource);
            img=im2double(g_img);
            img_dct=func3(app,img,data,smaller);
            img_idct=blkproc(img_dct,[8 8],'P1*x*P2',T',T);
            img_idct_gn=imnoise(img_idct,"gaussian");
            imshow(img_idct_gn,'Parent',app.UIAxes2);
            app.UIAxes2.Title.String="高斯白噪声处理后";
            data1=func(app,img_idct_gn);
            app.EditField_3.Value=func2(app,data1);
            imwrite(img_idct,'img_jepg.jpg',"jpeg","Quality",50);
            img_jepg=imread("img_jepg.jpg","jpg");
            imshow(img_jepg,'Parent',app.UIAxes2_2);
            app.UIAxes2_2.Title.String="jepg压缩处理后";
            data2=func(app,im2double(img_jepg));
            app.EditField_4.Value=func2(app,data2);
        end

        % Button pushed function: jepgButton
        function jepgButtonPushed(app, event)
            img=getimage(app.UIAxes);
            imwrite(img,'img_jepg.jpg',"jpeg","Quality",50);
            img_jepg=imread("img_jepg.jpg","jpg");
            imshow(img_jepg,'Parent',app.UIAxes2_2);
            app.UIAxes2_2.Title.String="jepg压缩处理后";
            data2=func(app,im2double(im2gray(img_jepg)));
            app.EditField_4.Value=func2(app,data2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '原图';
            app.DCTButton = uibutton(app.UIFigure, 'push');
            app.DCTButton.ButtonPushedFcn = createCallbackFcn(app, @DCTButtonPushed, true);
            app.DCTButton.Text = 'DCT嵌入';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '要嵌入的文字';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '从上图提取出';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '提取';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '加入噪声';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '提取出';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '健壮性参数调整';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Step = 0.02;
            app.Spinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanging, true);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.jepgButton = uibutton(app.UIFigure, 'push');
            app.jepgButton.ButtonPushedFcn = createCallbackFcn(app, @jepgButtonPushed, true);
            app.jepgButton.Text = 'jepg压缩';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '提取出';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end